classdef WarlockGearApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GearAndDPS                     matlab.ui.container.Tab
        NeckInt                        matlab.ui.control.NumericEditField
        NeckSP                         matlab.ui.control.NumericEditField
        NeckHit                        matlab.ui.control.NumericEditField
        NeckCrit                       matlab.ui.control.NumericEditField
        ShouldersInt                   matlab.ui.control.NumericEditField
        ShouldersSP                    matlab.ui.control.NumericEditField
        ShouldersHit                   matlab.ui.control.NumericEditField
        ShouldersCrit                  matlab.ui.control.NumericEditField
        BackInt                        matlab.ui.control.NumericEditField
        BackSP                         matlab.ui.control.NumericEditField
        BackHit                        matlab.ui.control.NumericEditField
        BackCrit                       matlab.ui.control.NumericEditField
        ChestInt                       matlab.ui.control.NumericEditField
        ChestSP                        matlab.ui.control.NumericEditField
        ChestHit                       matlab.ui.control.NumericEditField
        ChestCrit                      matlab.ui.control.NumericEditField
        WristsInt                      matlab.ui.control.NumericEditField
        WristsSP                       matlab.ui.control.NumericEditField
        WristsHit                      matlab.ui.control.NumericEditField
        WristsCrit                     matlab.ui.control.NumericEditField
        Finger1Int                     matlab.ui.control.NumericEditField
        Finger1SP                      matlab.ui.control.NumericEditField
        Finger1Hit                     matlab.ui.control.NumericEditField
        Finger1Crit                    matlab.ui.control.NumericEditField
        Finger2Int                     matlab.ui.control.NumericEditField
        Finger2SP                      matlab.ui.control.NumericEditField
        Finger2Hit                     matlab.ui.control.NumericEditField
        Finger2Crit                    matlab.ui.control.NumericEditField
        WaistInt                       matlab.ui.control.NumericEditField
        WaistSP                        matlab.ui.control.NumericEditField
        WaistHit                       matlab.ui.control.NumericEditField
        WaistCrit                      matlab.ui.control.NumericEditField
        LegsInt                        matlab.ui.control.NumericEditField
        LegsSP                         matlab.ui.control.NumericEditField
        LegsHit                        matlab.ui.control.NumericEditField
        LegsCrit                       matlab.ui.control.NumericEditField
        FeetInt                        matlab.ui.control.NumericEditField
        FeetSP                         matlab.ui.control.NumericEditField
        FeetHit                        matlab.ui.control.NumericEditField
        FeetCrit                       matlab.ui.control.NumericEditField
        Trinket1Int                    matlab.ui.control.NumericEditField
        Trinket1SP                     matlab.ui.control.NumericEditField
        Trinket1Hit                    matlab.ui.control.NumericEditField
        Trinket1Crit                   matlab.ui.control.NumericEditField
        Trinket2Int                    matlab.ui.control.NumericEditField
        Trinket2SP                     matlab.ui.control.NumericEditField
        Trinket2Hit                    matlab.ui.control.NumericEditField
        Trinket2Crit                   matlab.ui.control.NumericEditField
        WandInt                        matlab.ui.control.NumericEditField
        WandSP                         matlab.ui.control.NumericEditField
        WandHit                        matlab.ui.control.NumericEditField
        WandCrit                       matlab.ui.control.NumericEditField
        WeaponInt                      matlab.ui.control.NumericEditField
        WeaponSP                       matlab.ui.control.NumericEditField
        WeaponHit                      matlab.ui.control.NumericEditField
        WeaponCrit                     matlab.ui.control.NumericEditField
        OffhandInt                     matlab.ui.control.NumericEditField
        OffhandSP                      matlab.ui.control.NumericEditField
        OffhandHit                     matlab.ui.control.NumericEditField
        OffhandCrit                    matlab.ui.control.NumericEditField
        ReliableLabel                  matlab.ui.control.Label
        AverageLabel                   matlab.ui.control.Label
        ProbabilityofDoingMoreLabel    matlab.ui.control.Label
        TotalsLabel                    matlab.ui.control.Label
        ThantheEstimatedDPSLabel       matlab.ui.control.Label
        NumberofShadowBoltCastsEditFieldLabel  matlab.ui.control.Label
        NumberofEditFieldLabel         matlab.ui.control.Label
        NumberofLifeTapCasts           matlab.ui.control.NumericEditField
        NumberofShadowBoltCastsEditFieldLabel_2  matlab.ui.control.Label
        HeadDropDown_2Label            matlab.ui.control.Label
        HeadDropDown                   matlab.ui.control.DropDown
        NeckDropDown_2Label            matlab.ui.control.Label
        NeckDropDown                   matlab.ui.control.DropDown
        ShouldersDropDown_2Label       matlab.ui.control.Label
        ShouldersDropDown              matlab.ui.control.DropDown
        ChestDropDown_2Label           matlab.ui.control.Label
        ChestDropDown                  matlab.ui.control.DropDown
        WristsDropDownLabel            matlab.ui.control.Label
        WristsDropDown                 matlab.ui.control.DropDown
        HandsDropDown_2Label           matlab.ui.control.Label
        HandsDropDown                  matlab.ui.control.DropDown
        WaistDropDown_2Label           matlab.ui.control.Label
        WaistDropDown                  matlab.ui.control.DropDown
        LegsDropDown_2Label            matlab.ui.control.Label
        LegsDropDown                   matlab.ui.control.DropDown
        FeetDropDownLabel              matlab.ui.control.Label
        FeetDropDown                   matlab.ui.control.DropDown
        Finger1DropDownLabel           matlab.ui.control.Label
        Finger1DropDown                matlab.ui.control.DropDown
        Trinket1DropDown_2Label        matlab.ui.control.Label
        Trinket1DropDown               matlab.ui.control.DropDown
        Trinket2DropDown_2Label        matlab.ui.control.Label
        Trinket2DropDown               matlab.ui.control.DropDown
        WeaponDropDown_2Label          matlab.ui.control.Label
        WeaponDropDown                 matlab.ui.control.DropDown
        WandDropDown_2Label            matlab.ui.control.Label
        WandDropDown                   matlab.ui.control.DropDown
        OffhandDropDown_2Label         matlab.ui.control.Label
        OffhandDropDown                matlab.ui.control.DropDown
        BackDropDown_2Label            matlab.ui.control.Label
        BackDropDown                   matlab.ui.control.DropDown
        IntEditFieldLabel_4            matlab.ui.control.Label
        HeadInt                        matlab.ui.control.NumericEditField
        SPEditFieldLabel_4             matlab.ui.control.Label
        HeadSP                         matlab.ui.control.NumericEditField
        HitLabel_4                     matlab.ui.control.Label
        HeadHit                        matlab.ui.control.NumericEditField
        CritLabel_4                    matlab.ui.control.Label
        HeadCrit                       matlab.ui.control.NumericEditField
        FightLengthsecondsEditField_2Label  matlab.ui.control.Label
        FightLengthsecondsEditField    matlab.ui.control.NumericEditField
        IntEditFieldLabel_5            matlab.ui.control.Label
        HandsInt                       matlab.ui.control.NumericEditField
        SPEditFieldLabel_5             matlab.ui.control.Label
        HandsSP                        matlab.ui.control.NumericEditField
        HitLabel_5                     matlab.ui.control.Label
        HandsHit                       matlab.ui.control.NumericEditField
        CritLabel_5                    matlab.ui.control.Label
        HandsCrit                      matlab.ui.control.NumericEditField
        ExtremeLabel_3                 matlab.ui.control.Label
        PreferenceSlider               matlab.ui.control.Slider
        IntEditFieldLabel_6            matlab.ui.control.Label
        TotalInt                       matlab.ui.control.NumericEditField
        SPEditFieldLabel_6             matlab.ui.control.Label
        TotalSP                        matlab.ui.control.NumericEditField
        HitLabel_6                     matlab.ui.control.Label
        TotalHit                       matlab.ui.control.NumericEditField
        CritLabel_6                    matlab.ui.control.Label
        TotalCrit                      matlab.ui.control.NumericEditField
        MaxManaLabel                   matlab.ui.control.Label
        TotalMana                      matlab.ui.control.NumericEditField
        NumberofEditFieldLabel_4       matlab.ui.control.Label
        NumberofShadowBoltCasts        matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        EstimatedDPSEditFieldLabel_2   matlab.ui.control.Label
        EstimatedDPSEditField          matlab.ui.control.NumericEditField
        SaveDPSCheckBox                matlab.ui.control.CheckBox
        SavedDPSEditField              matlab.ui.control.NumericEditField
        SavedDifference                matlab.ui.control.NumericEditField
        DMTCheckBox                    matlab.ui.control.CheckBox
        OnyCheckBox                    matlab.ui.control.CheckBox
        HakkarCheckBox                 matlab.ui.control.CheckBox
        ShadowElixirCheckBox           matlab.ui.control.CheckBox
        GreaterArcaneElixirCheckBox    matlab.ui.control.CheckBox
        FlaskofSupremePowerCheckBox    matlab.ui.control.CheckBox
        BrilliantWizardOilCheckBox     matlab.ui.control.CheckBox
        Rank10ShadowboltCheckBox       matlab.ui.control.CheckBox
        MoonkinAuraCheckBox            matlab.ui.control.CheckBox
        SongflowerCheckBox             matlab.ui.control.CheckBox
        OptimizeButton                 matlab.ui.control.Button
        Finger2DropDownLabel           matlab.ui.control.Label
        Finger2DropDown                matlab.ui.control.DropDown
        SavedRelativeDifference        matlab.ui.control.EditField
        OptimizeGauge                  matlab.ui.control.LinearGauge
        ManaPotionsCheckBox            matlab.ui.control.CheckBox
        BlastedLandsIntCheckBox        matlab.ui.control.CheckBox
        ElixiroftheSagesCheckBox       matlab.ui.control.CheckBox
        DemonicRunesCheckBox           matlab.ui.control.CheckBox
        ArcaneIntellectCheckBox        matlab.ui.control.CheckBox
        AQ5setbonusCheckBox            matlab.ui.control.CheckBox
        ManaTideCheckBox               matlab.ui.control.CheckBox
        ManaSpringCheckBox             matlab.ui.control.CheckBox
        MagebloodCheckBox              matlab.ui.control.CheckBox
        MaxMana                        matlab.ui.control.NumericEditField
        TotalManaLabel                 matlab.ui.control.Label
        RendCheckBox                   matlab.ui.control.CheckBox
        TalentsTab                     matlab.ui.container.Tab
        TalentModificationsComingSoonLabel  matlab.ui.control.Label
        TotalsLabel_2                  matlab.ui.control.Label
        NumberofShadowBoltCastsEditFieldLabel_3  matlab.ui.control.Label
        NumberofEditFieldLabel_5       matlab.ui.control.Label
        NumberofLifeTapCasts_2         matlab.ui.control.NumericEditField
        NumberofShadowBoltCastsEditFieldLabel_4  matlab.ui.control.Label
        NumberofEditFieldLabel_6       matlab.ui.control.Label
        NumberofShadowBoltCasts_2      matlab.ui.control.NumericEditField
        CalculateButton_2              matlab.ui.control.Button
        SaveDPSCheckBox_2              matlab.ui.control.CheckBox
        SavedDPSEditField_2            matlab.ui.control.NumericEditField
        SavedDifference_2              matlab.ui.control.NumericEditField
        SavedRelativeDifference_2      matlab.ui.control.EditField
        FightLengthsecondsEditField_2Label_2  matlab.ui.control.Label
        FightLengthsecondsEditField_3  matlab.ui.control.NumericEditField
        IntEditFieldLabel_7            matlab.ui.control.Label
        TotalInt_2                     matlab.ui.control.NumericEditField
        SPEditFieldLabel_7             matlab.ui.control.Label
        TotalSP_2                      matlab.ui.control.NumericEditField
        HitLabel_7                     matlab.ui.control.Label
        TotalHit_2                     matlab.ui.control.NumericEditField
        CritLabel_7                    matlab.ui.control.Label
        TotalCrit_2                    matlab.ui.control.NumericEditField
        ManaLabel_3                    matlab.ui.control.Label
        TotalMana_2                    matlab.ui.control.NumericEditField
        EstimatedDPSEditField_2Label   matlab.ui.control.Label
        EstimatedDPSEditField_2        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Slot = string(zeros(34,1));
        Name = string(zeros(34,1));
        Int = zeros(34,1);
        SP = zeros(34,1);
        Hit = zeros(34,1);
        Crit = zeros(34,1);
        
        AQSetBonusIDs = 0;
        
        BaseLifeTap = 424;
        
        % These will be updated within the DPS functions if the Rank 10
        % option is selected; rank 9 is default
        BaseSBoltCost = 370; SBoltCost = 370;
        BaseSBoltDmgUB = 455;
        BaseSBoltDmgLB = 507;
        BaseSBoltDmg = (455+507)/2;
    end
    
    methods (Access = private)
        
        % ToDo: Really need to double check the defaults of 108 and 3.48
        % and 2713. It also might be 60.6 on the int-crit conversion
        function UpdateTotals(app)
            app.TotalInt.Value = app.HeadInt.Value + app.NeckInt.Value + app.ShouldersInt.Value + app.BackInt.Value + app.ChestInt.Value + ...
                                 app.WristsInt.Value + app.HandsInt.Value + app.WaistInt.Value + app.LegsInt.Value + app.FeetInt.Value + ...
                                 app.Finger1Int.Value + app.Finger2Int.Value + app.Trinket1Int.Value + app.Trinket2Int.Value + app.WeaponInt.Value + ...
                                 app.OffhandInt.Value + app.WandInt.Value + 108; % default of 108
                             
            app.TotalSP.Value = app.HeadSP.Value + app.NeckSP.Value + app.ShouldersSP.Value + app.BackSP.Value + app.ChestSP.Value + ...
                                app.WristsSP.Value + app.HandsSP.Value + app.WaistSP.Value + app.LegsSP.Value + app.FeetSP.Value + ...
                                app.Finger1SP.Value + app.Finger2SP.Value + app.Trinket1SP.Value + app.Trinket2SP.Value + app.WeaponSP.Value + ...
                                app.OffhandSP.Value + app.WandSP.Value;
            
            app.TotalHit.Value = app.HeadHit.Value + app.NeckHit.Value + app.ShouldersHit.Value + app.BackHit.Value + app.ChestHit.Value + ...
                                 app.WristsHit.Value + app.HandsHit.Value + app.WaistHit.Value + app.LegsHit.Value + app.FeetHit.Value + ...
                                 app.Finger1Hit.Value + app.Finger2Hit.Value + app.Trinket1Hit.Value + app.Trinket2Hit.Value + app.WeaponHit.Value + ...
                                 app.OffhandHit.Value + app.WandHit.Value;
                             
            app.TotalCrit.Value = app.HeadCrit.Value + app.NeckCrit.Value + app.ShouldersCrit.Value + app.BackCrit.Value + app.ChestCrit.Value + ...
                                  app.WristsCrit.Value + app.HandsCrit.Value + app.WaistCrit.Value + app.LegsCrit.Value + app.FeetCrit.Value + ...
                                  app.Finger1Crit.Value + app.Finger2Crit.Value + app.Trinket1Crit.Value + app.Trinket2Crit.Value + app.WeaponCrit.Value + ...
                                  app.OffhandCrit.Value + app.WandCrit.Value + 3.48; % + 3.48 baseline
                              
            if (app.DMTCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 3;
            end
                              
            if (app.OnyCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 10;
            end
                              
            if (app.HakkarCheckBox.Value)
                app.TotalInt.Value = app.TotalInt.Value * 1.15;
            end
            
            if (app.MoonkinAuraCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 3;
            end
            
            if (app.SongflowerCheckBox.Value)
                app.TotalCrit.Value = app.TotalCrit.Value + 5;
            end
            
            if (app.ShadowElixirCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 40;
            end
                              
            if (app.GreaterArcaneElixirCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 35;
            end
                              
            if (app.FlaskofSupremePowerCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 150;
            end
            
            if (app.BrilliantWizardOilCheckBox.Value)
                app.TotalSP.Value = app.TotalSP.Value + 36;
                app.TotalCrit.Value = app.TotalCrit.Value + 1;
            end
            
            if (app.BlastedLandsIntCheckBox.Value)
                app.TotalInt.Value = app.TotalInt.Value + 25;
            end
            
            if (app.ElixiroftheSagesCheckBox.Value)
                app.TotalInt.Value = app.TotalInt.Value + 18;
            end
            
            if (app.ArcaneIntellectCheckBox.Value)
                app.TotalInt.Value = app.TotalInt.Value + 31;
            end
                              
            app.TotalCrit.Value = app.TotalCrit.Value + app.TotalInt.Value/60;
            app.TotalMana.Value = app.TotalInt.Value * 15 + 2713; % + 2713 baseline
            app.MaxMana.Value = app.TotalMana.Value;
            
            % Direct mana-based consumes are after the initial conversion
            %
            % Technically these consumes have a variable return value,
            % so these calculations will only incorporate the average...
            %
            % Also, they are directly added to total mana pool since
            % mathematically they functionally contribute to extend the
            % stage 1 iterative calculation for N
            %
            % Buffer room of 15 seconds is added manually to account for
            % the extra needed time to actually cast the additional shadow
            % bolts...
            
            if (app.ManaPotionsCheckBox.Value)
                T = app.FightLengthsecondsEditField.Value;
                nBaseUse = floor(T/120); residual = mod(T,120);
                
                % These conditional can probably be simplified analytically
                if (T > 15)
                    if (nBaseUse == 0 && residual >= 15)
                        app.TotalMana.Value = app.TotalMana.Value + ...
                                                             (2250+1350)/2;
                    end
                    
                    if (nBaseUse > 0)
                       if (residual >= 30) % Full benefit
                           app.TotalMana.Value = app.TotalMana.Value + ...
                                                    (2250+1350)/2*nBaseUse;
                       elseif (residual >= 15) % Partial benefit
                           app.TotalMana.Value = app.TotalMana.Value + ...
                               (2250+1350)/2*(nBaseUse-1+(residual-15)/15);
                       else % only the benefit of past ones
                           app.TotalMana.Value = app.TotalMana.Value + ...
                                                (2250+1350)/2*(nBaseUse-1);
                       end
                    end
                end
            end
            
            if (app.DemonicRunesCheckBox.Value)
                T = app.FightLengthsecondsEditField.Value;
                nBaseUse = floor(T/120); residual = mod(T,120);
                
                % These conditional can probably be simplified analytically
                if (T > 15)
                    if (nBaseUse == 0 && residual >= 15)
                        app.TotalMana.Value = app.TotalMana.Value + ...
                                                              (1500+900)/2;
                    end
                    
                    if (nBaseUse > 0)
                       if (residual >= 30) % Full benefit
                           app.TotalMana.Value = app.TotalMana.Value + ...
                                                (1500+900)/2*floor(T/120);
                       elseif(residual >= 15) % Used potion, but full benefit cannot be gained
                           app.TotalMana.Value = app.TotalMana.Value + ...
                            (1500+900)/2*(floor(T/120)-1+(residual-15)/15);
                       end
                    end
                end
            end
            
            if (app.ManaTideCheckBox.Value)
                app.TotalMana.Value = app.TotalMana.Value + ...
                 290*4*floor(app.FightLengthsecondsEditField.Value/(60*5));
            end
            
            if (app.ManaSpringCheckBox.Value)
                app.TotalMana.Value = app.TotalMana.Value + ...
                    27*1.25*floor(app.FightLengthsecondsEditField.Value/2); % 1.25 from assumed resto talents
            end
            
            if (app.MagebloodCheckBox.Value)
                app.TotalMana.Value = app.TotalMana.Value + ...
                         12*floor(app.FightLengthsecondsEditField.Value/5);
            end
            
            if (app.RendCheckBox.Value)
                app.TotalMana.Value = app.TotalMana.Value + ...
                         10*floor(app.FightLengthsecondsEditField.Value/5);
            end
            
            % Update AQ set condition
            if (isequal([find(strcmp(app.Name,app.HeadDropDown.Value)) ...
                         find(strcmp(app.Name,app.ShouldersDropDown.Value)) ...
                         find(strcmp(app.Name,app.ChestDropDown.Value)) ...
                         find(strcmp(app.Name,app.LegsDropDown.Value)) ...
                         find(strcmp(app.Name,app.FeetDropDown.Value))], ...
                            app.AQSetBonusIDs))
                app.AQ5setbonusCheckBox.Value = 1;
                
                app.SBoltCost = app.BaseSBoltCost*0.85;
            else
                app.AQ5setbonusCheckBox.Value = 0;
                app.SBoltCost = app.BaseSBoltCost;
            end
        end
        
        
        function UpdateDPS(app)
            
            [NewDPS, N, NumberOfLifeTaps] = CalculateDPS(app, ...
                                  app.TotalSP.Value,app.TotalHit.Value, ...
                                  app.TotalCrit.Value,app.TotalMana.Value);
            
            app.EstimatedDPSEditField.Value = NewDPS;
            app.NumberofShadowBoltCasts.Value = N;
            app.NumberofLifeTapCasts.Value = NumberOfLifeTaps;
            
            if (app.SaveDPSCheckBox.Value)
                app.SavedDifference.Value = app.EstimatedDPSEditField.Value - app.SavedDPSEditField.Value;
                app.SavedRelativeDifference.Value = string(round(app.SavedDifference.Value/app.SavedDPSEditField.Value,2)*100) + "%";
                
                if (app.SavedDifference.Value == 0)
                    app.SavedDifference.FontColor = [0 0 0];
                    app.SavedRelativeDifference.FontColor = [0 0 0];
                end
                
                if (app.SavedDifference.Value > 0)
                    app.SavedDifference.FontColor = [0.47,0.67,0.19];
                    app.SavedRelativeDifference.FontColor = [0.47,0.67,0.19];
                end
                
                if (app.SavedDifference.Value < 0)
                    app.SavedDifference.FontColor = [0.64,0.08,0.18];
                    app.SavedRelativeDifference.FontColor = [0.64,0.08,0.18];
                end
            end
        end
        
        
        function [DPS,N,NumberOfLifeTaps] = CalculateDPS(app,TotalSP,TotalHit,TotalCrit,TotalMana)
            s = TotalSP;
            p = min(0.83 + TotalHit/100,0.99);
            q = TotalCrit/100;
            
            c = app.PreferenceSlider.Value;
            
            switch c
                case 0
                    c = 0.05;
                case 1
                    c = 0.95;
            end
            
            T = app.FightLengthsecondsEditField.Value;
            LifeTapAmount = (app.BaseLifeTap + 1.5/3.5 * s);
            Mseq = TotalMana;
            Nseq = min([floor(Mseq/app.SBoltCost) floor(T/2.5)]);
            eps = T - Nseq*2.5; j = 2;
            NumberOfLifeTaps = 0;
            while (eps >= 2.5)
                Mseq(j) = Mseq(j-1)/app.SBoltCost - floor(Mseq(j-1)/app.SBoltCost) + LifeTapAmount;
                Nseq(j) = floor(Mseq(j)/app.SBoltCost);
                eps = eps - 1.5; NumberOfLifeTaps = NumberOfLifeTaps + 1;
                k = Nseq(j);
                while (k > 0 && eps >= 2.5)
                    eps = eps - 2.5;
                    k = k - 1;
                end
                j = j + 1;
            end
            N = sum(Nseq);
            
            % ERROR: Need to fix the manual calculation of 962 and 6/3.5 to
            % account for rank 10 option
            mu = (app.BaseSBoltDmg + 3/3.5 * s) * p * (2 * q + 1);
            sigma = sqrt( (52^2/12 + (app.BaseSBoltDmg + 3/3.5 * s)^2) * p * (4 * q * (1 - q) + (2 * q + 1)^2) - (app.BaseSBoltDmg + 3/3.5 * s)^2 * p^2 * (2 * q + 1)^2 );
            
            DPS = (sqrt(2) * erfinv(2*(1-c)-1) * sqrt(N)*sigma + N*mu) / app.FightLengthsecondsEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Slot(1) = "Head";
            app.Slot(2) = "Head";
            app.Slot(3) = "Head";
            app.Slot(4) = "Neck";
            app.Slot(5) = "Neck";
            app.Slot(6) = "Neck";
            app.Slot(7) = "Shoulders";
            app.Slot(8) = "Shoulders";
            app.Slot(9) = "Back";
            app.Slot(10) = "Back";
            app.Slot(11) = "Chest";
            app.Slot(12) = "Chest";
            app.Slot(13) = "Chest";
            app.Slot(14) = "Wrists";
            app.Slot(15) = "Wrists";
            app.Slot(16) = "Hands";
            app.Slot(17) = "Hands";
            app.Slot(18) = "Hands";
            app.Slot(19) = "Waist";
            app.Slot(20) = "Waist";
            app.Slot(21) = "Legs";
            app.Slot(22) = "Feet";
            app.Slot(23) = "Feet";
            app.Slot(24) = "Finger";
            app.Slot(25) = "Finger";
            app.Slot(26) = "Finger";
            app.Slot(27) = "Finger";
            app.Slot(28) = "Finger";
            app.Slot(29) = "Finger";
            app.Slot(30) = "Trinket";
            app.Slot(31) = "Trinket";
            app.Slot(32) = "Weapon";
            app.Slot(33) = "Weapon";
            app.Slot(34) = "Wand";
            app.Slot(35) = "Legs";
            app.Slot(36) = "Feet";
            
            app.Name(1) = "Doomcaller's Circlet";
            app.Name(2) = "Mish'undare";
            app.Name(3) = "Hexer's Cover";
            app.Name(4) = "Soul Corrupter's Necklace";
            app.Name(5) = "Amulet of Vek'nilash";
            app.Name(6) = "Choker of the Fire Lord";
            app.Name(7) = "Doomcaller's Mantle";
            app.Name(8) = "Mantle of the Blackwing Cabal";
            app.Name(9) = "Cloak of the Devoured";
            app.Name(10) = "Cloak of the Broodlord";
            app.Name(11) = "Robe of Volatile Power";
            app.Name(12) = "Garb of Royal Ascension";
            app.Name(13) = "Doomcaller's Robes";
            app.Name(14) = "Burrower Bracers";
            app.Name(15) = "Bracers of Arcane Accuracy";
            app.Name(16) = "Dark Storm Gauntlets";
            app.Name(17) = "Nemesis Gloves";
            app.Name(18) = "Ebony Flame Gloves";
            app.Name(19) = "Eyestalk Waistcord";
            app.Name(20) = "Angelista's Grasp";
            app.Name(21) = "Leggings of the Black Blizzard";
            app.Name(22) = "Boots of Epiphany";
            app.Name(23) = "Bloodvine Feet";
            app.Name(24) = "Charm of the Shifting Sands";
            app.Name(25) = "Ritssyn's Ring of Chaos";
            app.Name(26) = "Ring of Unspoken Names";
            app.Name(27) = "Band of Forced Concentration";
            app.Name(28) = "Band of Dark Dominion";
            app.Name(29) = "Ring of Spell Power";
            app.Name(30) = "Talisman of Ephemeral Power";
            app.Name(31) = "Neltharion's Tear";
            app.Name(32) = "Staff of the Shadowflame";
            app.Name(33) = "Blessed Qiraji Acolyte Staff";
            app.Name(34) = "Wand of Qiraji Nobility";
            app.Name(35) = "Doomcaller's Trousers";
            app.Name(36) = "Doomcaller's Footwraps";
            
            app.AQSetBonusIDs = [1 7 13 35 36];
            
            app.Int(1) = 26; app.SP(1) = 33; app.Hit(1) = 1; app.Crit(1) = 1;
            app.Int(2) = 24; app.SP(2) = 35; app.Hit(2) = 0; app.Crit(2) = 2;
            app.Int(3) = 10; app.SP(3) = 41; app.Hit(3) = 0; app.Crit(3) = 0;
            app.Int(4) = 16; app.SP(4) = 0; app.Hit(4) = 1; app.Crit(4) = 0;
            app.Int(5) = 5; app.SP(5) = 27; app.Hit(5) = 0; app.Crit(5) = 1;
            app.Int(6) = 7; app.SP(6) = 34; app.Hit(6) = 0; app.Crit(6) = 0;
            app.Int(7) = 11; app.SP(7) = 28; app.Hit(7) = 1; app.Crit(7) = 0;
            app.Int(8) = 16; app.SP(8) = 34; app.Hit(8) = 0; app.Crit(8) = 0;
            app.Int(9) = 10; app.SP(9) = 30; app.Hit(9) = 1; app.Crit(9) = 0;
            app.Int(10) = 14; app.SP(10) = 28; app.Hit(10) = 0; app.Crit(10) = 0;
            app.Int(11) = 15; app.SP(11) = 23; app.Hit(11) = 0; app.Crit(11) = 2;
            app.Int(12) = 0; app.SP(12) = 30; app.Hit(12) = 2; app.Crit(12) = 0;
            app.Int(13) = 17; app.SP(13) = 41; app.Hit(13) = 0; app.Crit(13) = 1;
            app.Int(14) = 13; app.SP(14) = 28; app.Hit(14) = 0; app.Crit(14) = 0;
            app.Int(15) = 12; app.SP(15) = 21; app.Hit(15) = 1; app.Crit(15) = 0;
            app.Int(16) = 15; app.SP(16) = 37; app.Hit(16) = 1; app.Crit(16) = 0;
            app.Int(17) = 15; app.SP(17) = 15; app.Hit(17) = 0; app.Crit(17) = 1;
            app.Int(18) = 12; app.SP(18) = 43; app.Hit(18) = 0; app.Crit(18) = 0;
            app.Int(19) = 9; app.SP(19) = 41; app.Hit(19) = 0; app.Crit(19) = 1;
            app.Int(20) = 20; app.SP(20) = 0; app.Hit(20) = 2; app.Crit(20) = 0;
            app.Int(21) = 16; app.SP(21) = 41; app.Hit(21) = 0; app.Crit(21) = 1;
            app.Int(22) = 19; app.SP(22) = 34; app.Hit(22) = 0; app.Crit(22) = 0;
            app.Int(23) = 16; app.SP(23) = 19; app.Hit(23) = 1; app.Crit(23) = 0;
            app.Int(24) = 12; app.SP(24) = 25; app.Hit(24) = 0; app.Crit(24) = 0;
            app.Int(25) = 0; app.SP(25) = 25; app.Hit(25) = 0; app.Crit(25) = 1;
            app.Int(26) = 0; app.SP(26) = 14; app.Hit(26) = 1; app.Crit(26) = 1;
            app.Int(27) = 12; app.SP(27) = 21; app.Hit(27) = 1; app.Crit(27) = 0;
            app.Int(28) = 6; app.SP(28) = 33; app.Hit(28) = 0; app.Crit(28) = 0;
            app.Int(29) = 0; app.SP(29) = 33; app.Hit(29) = 0; app.Crit(29) = 0;
            app.Int(30) = 0; app.SP(30) = 176*6/90; app.Hit(30) = 0; app.Crit(30) = 0;
            app.Int(31) = 0; app.SP(31) = 44; app.Hit(31) = 2; app.Crit(31) = 0;
            app.Int(32) = 29; app.SP(32) = 84; app.Hit(32) = 0; app.Crit(32) = 2;
            app.Int(33) = 33; app.SP(33) = 76; app.Hit(33) = 2; app.Crit(33) = 1;
            app.Int(34) = 0; app.SP(34) = 19; app.Hit(34) = 0; app.Crit(34) = 0;
            app.Int(35) = 24; app.SP(35) = 34; app.Hit(35) = 0; app.Crit(35) = 1;
            app.Int(36) = 16; app.SP(36) = 28; app.Hit(36) = 0; app.Crit(36) = 0;
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            IDs.Head = find(strcmp(app.Slot,"Head")); Nums.Head = numel(IDs.Head);
            IDs.Neck = find(strcmp(app.Slot,"Neck")); Nums.Neck = numel(IDs.Neck);
            IDs.Shoulders = find(strcmp(app.Slot,"Shoulders")); Nums.Shoulders = numel(IDs.Shoulders);
            IDs.Back = find(strcmp(app.Slot,"Back")); Nums.Back = numel(IDs.Back);
            IDs.Chest = find(strcmp(app.Slot,"Chest")); Nums.Chest = numel(IDs.Chest);
            IDs.Wrists = find(strcmp(app.Slot,"Wrists")); Nums.Wrists = numel(IDs.Wrists);
            
            IDs.Hands = find(strcmp(app.Slot,"Hands")); Nums.Hands = numel(IDs.Hands);
            IDs.Waist = find(strcmp(app.Slot,"Waist")); Nums.Waist = numel(IDs.Waist);
            IDs.Legs = find(strcmp(app.Slot,"Legs")); Nums.Legs = numel(IDs.Legs);
            IDs.Feet = find(strcmp(app.Slot,"Feet")); Nums.Feet = numel(IDs.Feet);
            IDs.Finger = find(strcmp(app.Slot,"Finger")); Nums.Finger = numel(IDs.Finger);
            
            IDs.Weapon = find(strcmp(app.Slot,"Weapon")); Nums.Weapon = numel(IDs.Weapon);
            IDs.Wand = find(strcmp(app.Slot,"Wand")); Nums.Wand = numel(IDs.Wand);
            
            nSlots = numel(fields(IDs)) + 1;
            ArrayInds = ones(nSlots,1);
            
            temp = table2array(struct2table(Nums));
            MaxCombos = prod(temp(1:10)) * nchoosek(temp(11),2) * prod(temp(12:end));
            
            Combo = cell(MaxCombos,1);
            ComboInt = zeros(MaxCombos,1);
            ComboSP = zeros(MaxCombos,1);
            ComboCrit = zeros(MaxCombos,1);
            ComboHit = zeros(MaxCombos,1);
            ComboDPS = zeros(MaxCombos,1);
            ComboMana = zeros(MaxCombos,1);
            
            counter = 1; % total counter
            for i1=1:Nums.Head
            for i2=1:Nums.Neck
            for i3=1:Nums.Shoulders
            for i4=1:Nums.Back
            for i5=1:Nums.Chest
            for i6=1:Nums.Wrists
            for i7=1:Nums.Hands
            for i8=1:Nums.Waist
            for i9=1:Nums.Legs
            for i10=1:Nums.Feet
            for i11=1:Nums.Finger
            for i12=1:Nums.Finger
            for i13=1:Nums.Weapon
            for i14=1:Nums.Wand
            
            if (counter/MaxCombos > (app.OptimizeGauge.Value+0.01))
                app.OptimizeGauge.Value = app.OptimizeGauge.Value + 0.01;
                drawnow
            end
                
            ArrayInds(1) = i1;
            ArrayInds(2) = i2;
            ArrayInds(3) = i3;
            ArrayInds(4) = i4;
            ArrayInds(5) = i5;
            ArrayInds(6) = i6;
            ArrayInds(7) = i7;
            ArrayInds(8) = i8;
            ArrayInds(9) = i9;
            ArrayInds(10) = i10;
            ArrayInds(11) = i11; % Ring 1
            ArrayInds(12) = i12; % Ring 2
            ArrayInds(13) = i13;
            ArrayInds(14) = i14;
            
                if (i12 > i11)
                    
                Combo{counter} = ArrayInds;
                
                ComboInt(counter) = sum(app.Int([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Int.Value + app.Trinket2Int.Value;
                
                ComboSP(counter) = sum(app.SP([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1SP.Value + app.Trinket2SP.Value;
                
                ComboCrit(counter) = sum(app.Hit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Crit.Value + app.Trinket2Crit.Value;
                
                ComboHit(counter) = sum(app.Hit([IDs.Head(ArrayInds(1)) IDs.Neck(ArrayInds(2)) IDs.Shoulders(ArrayInds(3)) ...
                    IDs.Back(ArrayInds(4)) IDs.Chest(ArrayInds(5)) IDs.Wrists(ArrayInds(6)) IDs.Hands(ArrayInds(7)) IDs.Waist(ArrayInds(8)) ...
                    IDs.Legs(ArrayInds(9)) IDs.Feet(ArrayInds(10)) IDs.Finger(ArrayInds(11)) IDs.Finger(ArrayInds(12)) IDs.Weapon(ArrayInds(13)) ...
                    IDs.Wand(ArrayInds(14))])) + app.Trinket1Hit.Value + app.Trinket2Hit.Value;
                
                if (app.DMTCheckBox.Value)
                    ComboCrit(counter) = ComboCrit(counter) + 3;
                end
                                  
                if (app.OnyCheckBox.Value)
                    ComboCrit(counter) = ComboCrit(counter) + 10;
                end
                                  
                if (app.HakkarCheckBox.Value)
                    ComboInt(counter) = ComboInt(counter) * 1.15;
                end
                
                if (app.MoonkinAuraCheckBox.Value)
                    ComboCrit(counter) = ComboCrit(counter) + 3;
                end
                
                if (app.SongflowerCheckBox.Value)
                    ComboCrit(counter) = ComboCrit(counter) + 5;
                end
                
                if (app.ShadowElixirCheckBox.Value)
                    ComboSP(counter) = ComboSP(counter) + 40;
                end
                                  
                if (app.GreaterArcaneElixirCheckBox.Value)
                    ComboSP(counter) = ComboSP(counter) + 35;
                end
                                  
                if (app.FlaskofSupremePowerCheckBox.Value)
                    ComboSP(counter) = ComboSP(counter) + 150;
                end
                
                if (app.BrilliantWizardOilCheckBox.Value)
                    ComboSP(counter) = ComboSP(counter) + 36;
                    ComboCrit(counter) = ComboCrit(counter) + 1;
                end
                
                if (app.BlastedLandsIntCheckBox.Value)
                    ComboInt(counter) = ComboInt(counter) + 25;
                end
                
                if (app.ElixiroftheSagesCheckBox.Value)
                    ComboInt(counter) = ComboInt(counter) + 18;
                end
                
                if (app.ArcaneIntellectCheckBox.Value)
                    ComboInt(counter) = ComboInt(counter) + 31;
                end
                                  
                ComboCrit(counter) = ComboCrit(counter) + ComboInt(counter)/60;          
                ComboMana(counter) = ComboInt(counter) * 15 + 2713; % + 2713 baseline
            
                % Direct mana-based consumes are after the initial conversion
                %
                % Technically these consumes have a variable return value,
                % so these calculations will only incorporate the average...
                %
                % Also, they are directly added to total mana pool since
                % mathematically they functionally contribute to extend the
                % stage 1 iterative calculation for N
                %
                % Buffer room of 15 seconds is added manually to account for
                % the extra needed time to actually cast the additional shadow
                % bolts...
                
                if (app.ManaPotionsCheckBox.Value)
                    T = app.FightLengthsecondsEditField.Value;
                    nBaseUse = floor(T/120); residual = mod(T,120);
                    
                    % These conditional can probably be simplified analytically
                    if (T > 15)
                        if (nBaseUse == 0 && residual >= 15)
                            ComboMana(counter) = ComboMana(counter) + ...
                                                             (2250+1350)/2;
                        end
                        
                        if (nBaseUse > 0)
                           if (residual >= 30) % Full benefit
                               ComboMana(counter) = ComboMana(counter) + ...
                                                (2250+1350)/2*floor(T/120);
                           elseif(residual >= 15) % Used potion, but full benefit cannot be gained
                               ComboMana(counter) = ComboMana(counter) + ...
                                 (2250+1350)/2*(floor(T/120)-1+(residual-15)/15);
                           end
                        end
                    end
                end
                
                if (app.DemonicRunesCheckBox.Value)
                    T = app.FightLengthsecondsEditField.Value;
                    nBaseUse = floor(T/120); residual = mod(T,120);
                    
                    % These conditional can probably be simplified analytically
                    if (T > 15)
                        if (nBaseUse == 0 && residual >= 15)
                            ComboMana(counter) = ComboMana(counter) + ...
                                                                  (1500+900)/2;
                        end
                        
                        if (nBaseUse > 0)
                           if (residual >= 30) % Full benefit
                               ComboMana(counter) = ComboMana(counter) + ...
                                                    (1500+900)/2*floor(T/120);
                           elseif(residual >= 15) % Used potion, but full benefit cannot be gained
                               ComboMana(counter) = ComboMana(counter) + ...
                                (1500+900)/2*(floor(T/120)-1+(residual-15)/15);
                           end
                        end
                    end
                end
                
                if (app.ManaTideCheckBox.Value)
                    ComboMana(counter) = ComboMana(counter) + 290*4;
                end
                
                if (app.ManaSpringCheckBox.Value)
                    ComboMana(counter) = ComboMana(counter) + ...
                        27*1.25*floor(app.FightLengthsecondsEditField.Value/2); % 1.25 from assumed resto talents
                end
                
                if (app.MagebloodCheckBox.Value)
                    ComboMana(counter) = ComboMana(counter) + ...
                             12*floor(app.FightLengthsecondsEditField.Value/5);
                end
                
                if (app.RendCheckBox.Value)
                    app.TotalMana.Value = app.TotalMana.Value + ...
                             10*floor(app.FightLengthsecondsEditField.Value/5);
                end
                
                % Update AQ set condition
                if (isequal([IDs.Head(ArrayInds(1)) ...
                             IDs.Shoulders(ArrayInds(3)) ...
                             IDs.Chest(ArrayInds(5)) ...
                             IDs.Legs(ArrayInds(9)) ...
                             IDs.Feet(ArrayInds(10))], ...
                                app.AQSetBonusIDs))
                    
                    app.SBoltCost = app.BaseSBoltCost*0.85;
                else
                    app.SBoltCost = app.BaseSBoltCost;
                end
                
                ComboDPS(counter) = CalculateDPS(app,ComboSP(counter),ComboHit(counter),ComboCrit(counter),ComboMana(counter));
                counter = counter + 1;
                end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            
            MaxInd = find(ComboDPS == max(ComboDPS));
            
            app.HeadDropDown.Value = app.Name(IDs.Head(Combo{MaxInd}(1)));
            HeadDropDownValueChanged(app,event)
            app.NeckDropDown.Value = app.Name(IDs.Neck(Combo{MaxInd}(2)));
            NeckDropDownValueChanged(app,event)
            app.ShouldersDropDown.Value = app.Name(IDs.Shoulders(Combo{MaxInd}(3)));
            ShouldersDropDownValueChanged(app,event)
            app.BackDropDown.Value = app.Name(IDs.Back(Combo{MaxInd}(4)));
            BackDropDownValueChanged(app,event)
            app.ChestDropDown.Value = app.Name(IDs.Chest(Combo{MaxInd}(5)));
            ChestDropDownValueChanged(app,event)
            app.WristsDropDown.Value = app.Name(IDs.Wrists(Combo{MaxInd}(6)));
            WristsDropDownValueChanged(app,event)
            app.HandsDropDown.Value = app.Name(IDs.Hands(Combo{MaxInd}(7)));
            HandsDropDownValueChanged(app,event)
            app.WaistDropDown.Value = app.Name(IDs.Waist(Combo{MaxInd}(8)));
            WaistDropDownValueChanged(app,event)
            app.LegsDropDown.Value = app.Name(IDs.Legs(Combo{MaxInd}(9)));
            LegsDropDownValueChanged(app,event)
            app.FeetDropDown.Value = app.Name(IDs.Feet(Combo{MaxInd}(10)));
            FeetDropDownValueChanged(app,event)
            app.Finger1DropDown.Value = app.Name(IDs.Finger(Combo{MaxInd}(11)));
            Finger1DropDownValueChanged(app,event)
            app.Finger2DropDown.Value = app.Name(IDs.Finger(Combo{MaxInd}(12)));
            Finger2DropDownValueChanged(app,event)
            app.WeaponDropDown.Value = app.Name(IDs.Weapon(Combo{MaxInd}(13)));
            WeaponDropDownValueChanged(app,event)
            app.WandDropDown.Value = app.Name(IDs.Wand(Combo{MaxInd}(14)));
            WandDropDownValueChanged(app,event)
            
            app.OptimizeGauge.Value = 0;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            UpdateTotals(app)
            UpdateDPS(app)
        end

        % Value changed function: SaveDPSCheckBox
        function SaveDPSCheckBoxValueChanged(app, event)
            if (app.SaveDPSCheckBox.Value)
                app.SavedDPSEditField.Value = app.EstimatedDPSEditField.Value;
                app.SavedDifference.Value = app.EstimatedDPSEditField.Value - app.SavedDPSEditField.Value;
                app.SavedRelativeDifference.Value = string(round(app.SavedDifference.Value/app.SavedDPSEditField.Value,2)*100) + "%";
                
                if (app.SavedDifference.Value == 0)
                    app.SavedDifference.FontColor = [0 0 0];
                    app.SavedRelativeDifference.FontColor = [0 0 0];
                end
                
                if (app.SavedDifference.Value > 0)
                    app.SavedDifference.FontColor = [0.47,0.67,0.19];
                    app.SavedRelativeDifference.FontColor = [0.47,0.67,0.19];
                end
                
                if (app.SavedDifference.Value < 0)
                    app.SavedDifference.FontColor = [0.64,0.08,0.18];
                    app.SavedRelativeDifference.FontColor = [0.64,0.08,0.18];
                end
            else
                app.SavedDPSEditField.Value = 0;
                app.SavedDifference.Value = 0;
                app.SavedRelativeDifference.Value = "0";
                app.SavedDifference.FontColor = [0 0 0];
                app.SavedRelativeDifference.FontColor = [0 0 0];
            end
        end

        % Value changed function: HeadDropDown
        function HeadDropDownValueChanged(app, event)
            CurrentItemValues = [app.HeadInt.Value app.HeadSP.Value app.HeadHit.Value app.HeadCrit.Value];
            CurrentItemName = app.HeadDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];

            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.HeadInt.Value = DatabaseItemValues(1);
                app.HeadSP.Value = DatabaseItemValues(2);
                app.HeadHit.Value = DatabaseItemValues(3);
                app.HeadCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: NeckDropDown
        function NeckDropDownValueChanged(app, event)
            CurrentItemValues = [app.NeckInt.Value app.NeckSP.Value app.NeckHit.Value app.NeckCrit.Value];
            CurrentItemName = app.NeckDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.NeckInt.Value = DatabaseItemValues(1);
                app.NeckSP.Value = DatabaseItemValues(2);
                app.NeckHit.Value = DatabaseItemValues(3);
                app.NeckCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ShouldersDropDown
        function ShouldersDropDownValueChanged(app, event)
            CurrentItemValues = [app.ShouldersInt.Value app.ShouldersSP.Value app.ShouldersHit.Value app.ShouldersCrit.Value];
            CurrentItemName = app.ShouldersDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.ShouldersInt.Value = DatabaseItemValues(1);
                app.ShouldersSP.Value = DatabaseItemValues(2);
                app.ShouldersHit.Value = DatabaseItemValues(3);
                app.ShouldersCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: BackDropDown
        function BackDropDownValueChanged(app, event)
            CurrentItemValues = [app.BackInt.Value app.BackSP.Value app.BackHit.Value app.BackCrit.Value];
            CurrentItemName = app.BackDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.BackInt.Value = DatabaseItemValues(1);
                app.BackSP.Value = DatabaseItemValues(2);
                app.BackHit.Value = DatabaseItemValues(3);
                app.BackCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ChestDropDown
        function ChestDropDownValueChanged(app, event)
            CurrentItemValues = [app.ChestInt.Value app.ChestSP.Value app.ChestHit.Value app.ChestCrit.Value];
            CurrentItemName = app.ChestDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.ChestInt.Value = DatabaseItemValues(1);
                app.ChestSP.Value = DatabaseItemValues(2);
                app.ChestHit.Value = DatabaseItemValues(3);
                app.ChestCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WristsDropDown
        function WristsDropDownValueChanged(app, event)
            CurrentItemValues = [app.WristsInt.Value app.WristsSP.Value app.WristsHit.Value app.WristsCrit.Value];
            CurrentItemName = app.WristsDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.WristsInt.Value = DatabaseItemValues(1);
                app.WristsSP.Value = DatabaseItemValues(2);
                app.WristsHit.Value = DatabaseItemValues(3);
                app.WristsCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: HandsDropDown
        function HandsDropDownValueChanged(app, event)
            CurrentItemValues = [app.HandsInt.Value app.HandsSP.Value app.HandsHit.Value app.HandsCrit.Value];
            CurrentItemName = app.HandsDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.HandsInt.Value = DatabaseItemValues(1);
                app.HandsSP.Value = DatabaseItemValues(2);
                app.HandsHit.Value = DatabaseItemValues(3);
                app.HandsCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WaistDropDown
        function WaistDropDownValueChanged(app, event)
            CurrentItemValues = [app.WaistInt.Value app.WaistSP.Value app.WaistHit.Value app.WaistCrit.Value];
            CurrentItemName = app.WaistDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.WaistInt.Value = DatabaseItemValues(1);
                app.WaistSP.Value = DatabaseItemValues(2);
                app.WaistHit.Value = DatabaseItemValues(3);
                app.WaistCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: LegsDropDown
        function LegsDropDownValueChanged(app, event)
            CurrentItemValues = [app.LegsInt.Value app.LegsSP.Value app.LegsHit.Value app.LegsCrit.Value];
            CurrentItemName = app.LegsDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.LegsInt.Value = DatabaseItemValues(1);
                app.LegsSP.Value = DatabaseItemValues(2);
                app.LegsHit.Value = DatabaseItemValues(3);
                app.LegsCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: FeetDropDown
        function FeetDropDownValueChanged(app, event)
            CurrentItemValues = [app.FeetInt.Value app.FeetSP.Value app.FeetHit.Value app.FeetCrit.Value];
            CurrentItemName = app.FeetDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.FeetInt.Value = DatabaseItemValues(1);
                app.FeetSP.Value = DatabaseItemValues(2);
                app.FeetHit.Value = DatabaseItemValues(3);
                app.FeetCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: Finger1DropDown
        function Finger1DropDownValueChanged(app, event)
            CurrentItemValues = [app.Finger1Int.Value app.Finger1SP.Value app.Finger1Hit.Value app.Finger1Crit.Value];
            CurrentItemName = app.Finger1DropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.Finger1Int.Value = DatabaseItemValues(1);
                app.Finger1SP.Value = DatabaseItemValues(2);
                app.Finger1Hit.Value = DatabaseItemValues(3);
                app.Finger1Crit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: Finger2DropDown
        function Finger2DropDownValueChanged(app, event)
            CurrentItemValues = [app.Finger2Int.Value app.Finger2SP.Value app.Finger2Hit.Value app.Finger2Crit.Value];
            CurrentItemName = app.Finger2DropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.Finger2Int.Value = DatabaseItemValues(1);
                app.Finger2SP.Value = DatabaseItemValues(2);
                app.Finger2Hit.Value = DatabaseItemValues(3);
                app.Finger2Crit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WeaponDropDown
        function WeaponDropDownValueChanged(app, event)
            CurrentItemValues = [app.WeaponInt.Value app.WeaponSP.Value app.WeaponHit.Value app.WeaponCrit.Value];
            CurrentItemName = app.WeaponDropDown.Value;
            DatabaseID = find(strcmp(app.Name,CurrentItemName));
            DatabaseItemValues = [app.Int(DatabaseID) app.SP(DatabaseID) app.Hit(DatabaseID) app.Crit(DatabaseID)];
            
            if (~isequal(CurrentItemValues,DatabaseItemValues))
                app.WeaponInt.Value = DatabaseItemValues(1);
                app.WeaponSP.Value = DatabaseItemValues(2);
                app.WeaponHit.Value = DatabaseItemValues(3);
                app.WeaponCrit.Value = DatabaseItemValues(4);
            end
            
            UpdateTotals(app);
            
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: WandDropDown
        function WandDropDownValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ShadowElixirCheckBox
        function ShadowElixirCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: GreaterArcaneElixirCheckBox
        function GreaterArcaneElixirCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: FlaskofSupremePowerCheckBox
        function FlaskofSupremePowerCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: DMTCheckBox
        function DMTCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: OnyCheckBox
        function OnyCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: HakkarCheckBox
        function HakkarCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: SongflowerCheckBox
        function SongflowerCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: BrilliantWizardOilCheckBox
        function BrilliantWizardOilCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: Rank10ShadowboltCheckBox
        function Rank10ShadowboltCheckBoxValueChanged(app, event)
            if (app.Rank10ShadowboltCheckBox.Value)
                app.SBoltCost = 380;
                app.BaseSBoltDmgUB = 538;
                app.BaseSBoltDmgLB = 482;
            else % Rank 9, default
                app.SBoltCost = 370;
                app.BaseSBoltDmgUB = 507;
                app.BaseSBoltDmgLB = 455;
            end
            app.BaseSBoltDmg = (app.BaseSBoltDmgLB+app.BaseSBoltDmgUB)/2;
            
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: MoonkinAuraCheckBox
        function MoonkinAuraCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: BlastedLandsIntCheckBox
        function BlastedLandsIntCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ElixiroftheSagesCheckBox
        function ElixiroftheSagesCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ArcaneIntellectCheckBox
        function ArcaneIntellectCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ManaPotionsCheckBox
        function ManaPotionsCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: DemonicRunesCheckBox
        function DemonicRunesCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ManaTideCheckBox
        function ManaTideCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: ManaSpringCheckBox
        function ManaSpringCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: MagebloodCheckBox
        function MagebloodCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end

        % Value changed function: RendCheckBox
        function RendCheckBoxValueChanged(app, event)
            UpdateTotals(app)
            if (app.EstimatedDPSEditField.Value ~= 0)
                UpdateDPS(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GearAndDPS = uitab(app.TabGroup);
            app.GearAndDPS.Title = 'Gear and DPS';
            app.NeckInt = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckInt.Editable = 'off';
            app.NeckInt.Value = 16;
            app.NeckSP = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckSP.Editable = 'off';
            app.NeckHit = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckHit.Editable = 'off';
            app.NeckHit.Value = 1;
            app.NeckCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.NeckCrit.Editable = 'off';
            app.ShouldersInt = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersInt.Editable = 'off';
            app.ShouldersInt.Value = 11;
            app.ShouldersSP = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersSP.Editable = 'off';
            app.ShouldersSP.Value = 28;
            app.ShouldersHit = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersHit.Editable = 'off';
            app.ShouldersHit.Value = 1;
            app.ShouldersCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.ShouldersCrit.Editable = 'off';
            app.BackInt = uieditfield(app.GearAndDPS, 'numeric');
            app.BackInt.Editable = 'off';
            app.BackInt.Value = 10;
            app.BackSP = uieditfield(app.GearAndDPS, 'numeric');
            app.BackSP.Editable = 'off';
            app.BackSP.Value = 30;
            app.BackHit = uieditfield(app.GearAndDPS, 'numeric');
            app.BackHit.Editable = 'off';
            app.BackHit.Value = 1;
            app.BackCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.BackCrit.Editable = 'off';
            app.ChestInt = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestInt.Editable = 'off';
            app.ChestInt.Value = 15;
            app.ChestSP = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestSP.Editable = 'off';
            app.ChestSP.Value = 23;
            app.ChestHit = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestHit.Editable = 'off';
            app.ChestCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.ChestCrit.Editable = 'off';
            app.ChestCrit.Value = 2;
            app.WristsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WristsInt.Editable = 'off';
            app.WristsInt.Value = 13;
            app.WristsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WristsSP.Editable = 'off';
            app.WristsSP.Value = 28;
            app.WristsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WristsHit.Editable = 'off';
            app.WristsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WristsCrit.Editable = 'off';
            app.Finger1Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1Int.Editable = 'off';
            app.Finger1Int.Value = 12;
            app.Finger1SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1SP.Editable = 'off';
            app.Finger1SP.Value = 21;
            app.Finger1Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1Hit.Editable = 'off';
            app.Finger1Hit.Value = 1;
            app.Finger1Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger1Crit.Editable = 'off';
            app.Finger2Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2Int.Editable = 'off';
            app.Finger2SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2SP.Editable = 'off';
            app.Finger2SP.Value = 33;
            app.Finger2Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2Hit.Editable = 'off';
            app.Finger2Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Finger2Crit.Editable = 'off';
            app.WaistInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistInt.Editable = 'off';
            app.WaistInt.Value = 9;
            app.WaistSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistSP.Editable = 'off';
            app.WaistSP.Value = 41;
            app.WaistHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistHit.Editable = 'off';
            app.WaistCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WaistCrit.Editable = 'off';
            app.WaistCrit.Value = 1;
            app.LegsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsInt.Editable = 'off';
            app.LegsInt.Value = 16;
            app.LegsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsSP.Editable = 'off';
            app.LegsSP.Value = 41;
            app.LegsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsHit.Editable = 'off';
            app.LegsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.LegsCrit.Editable = 'off';
            app.LegsCrit.Value = 1;
            app.FeetInt = uieditfield(app.GearAndDPS, 'numeric');
            app.FeetInt.Editable = 'off';
            app.FeetInt.Value = 19;
            app.FeetSP = uieditfield(app.GearAndDPS, 'numeric');
            app.FeetSP.Editable = 'off';
            app.FeetSP.Value = 34;
            app.FeetHit = uieditfield(app.GearAndDPS, 'numeric');
            app.FeetHit.Editable = 'off';
            app.FeetCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.FeetCrit.Editable = 'off';
            app.Trinket1Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Int.Editable = 'off';
            app.Trinket1SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1SP.Editable = 'off';
            app.Trinket1SP.Value = 11.73333;
            app.Trinket1Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Hit.Editable = 'off';
            app.Trinket1Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket1Crit.Editable = 'off';
            app.Trinket2Int = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Int.Editable = 'off';
            app.Trinket2SP = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2SP.Editable = 'off';
            app.Trinket2SP.Value = 44;
            app.Trinket2Hit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Hit.Editable = 'off';
            app.Trinket2Hit.Value = 2;
            app.Trinket2Crit = uieditfield(app.GearAndDPS, 'numeric');
            app.Trinket2Crit.Editable = 'off';
            app.WandInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WandInt.Editable = 'off';
            app.WandSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WandSP.Editable = 'off';
            app.WandSP.Value = 19;
            app.WandHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WandHit.Editable = 'off';
            app.WandCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WandCrit.Editable = 'off';
            app.WeaponInt = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponInt.Editable = 'off';
            app.WeaponInt.Value = 33;
            app.WeaponSP = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponSP.Editable = 'off';
            app.WeaponSP.Value = 76;
            app.WeaponHit = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponHit.Editable = 'off';
            app.WeaponHit.Value = 2;
            app.WeaponCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.WeaponCrit.Editable = 'off';
            app.WeaponCrit.Value = 1;
            app.OffhandInt = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandInt.Editable = 'off';
            app.OffhandSP = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandSP.Editable = 'off';
            app.OffhandHit = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandHit.Editable = 'off';
            app.OffhandCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.OffhandCrit.Editable = 'off';
            app.ReliableLabel = uilabel(app.GearAndDPS);
            app.ReliableLabel.Text = 'Reliable';
            app.AverageLabel = uilabel(app.GearAndDPS);
            app.AverageLabel.Text = 'Average';
            app.ProbabilityofDoingMoreLabel = uilabel(app.GearAndDPS);
            app.ProbabilityofDoingMoreLabel.Text = 'Probability of Doing More';
            app.TotalsLabel = uilabel(app.GearAndDPS);
            app.TotalsLabel.Text = 'Totals';
            app.ThantheEstimatedDPSLabel = uilabel(app.GearAndDPS);
            app.ThantheEstimatedDPSLabel.Text = 'Than the Estimated DPS';
            app.NumberofShadowBoltCastsEditFieldLabel = uilabel(app.GearAndDPS);
            app.NumberofShadowBoltCastsEditFieldLabel.Text = 'Shadow Bolt Casts';
            app.NumberofEditFieldLabel = uilabel(app.GearAndDPS);
            app.NumberofEditFieldLabel.Text = 'Number of ';
            app.NumberofLifeTapCasts = uieditfield(app.GearAndDPS, 'numeric');
            app.NumberofLifeTapCasts.Editable = 'off';
            app.NumberofShadowBoltCastsEditFieldLabel_2 = uilabel(app.GearAndDPS);
            app.NumberofShadowBoltCastsEditFieldLabel_2.Text = 'Life Tap Casts';
            app.HeadDropDown_2Label = uilabel(app.GearAndDPS);
            app.HeadDropDown_2Label.Text = 'Head';
            app.HeadDropDown = uidropdown(app.GearAndDPS);
            app.HeadDropDown.Items = {'Doomcaller''s Circlet', 'Mish''undare', 'Hexer''s Cover'};
            app.HeadDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadDropDownValueChanged, true);
            app.HeadDropDown.Value = 'Doomcaller''s Circlet';
            app.NeckDropDown_2Label = uilabel(app.GearAndDPS);
            app.NeckDropDown_2Label.Text = 'Neck';
            app.NeckDropDown = uidropdown(app.GearAndDPS);
            app.NeckDropDown.Items = {'Soul Corrupter''s Necklace', 'Amulet of Vek''nilash', 'Choker of the Fire Lord'};
            app.NeckDropDown.ValueChangedFcn = createCallbackFcn(app, @NeckDropDownValueChanged, true);
            app.NeckDropDown.Value = 'Soul Corrupter''s Necklace';
            app.ShouldersDropDown_2Label = uilabel(app.GearAndDPS);
            app.ShouldersDropDown_2Label.Text = 'Shoulders';
            app.ShouldersDropDown = uidropdown(app.GearAndDPS);
            app.ShouldersDropDown.Items = {'Doomcaller''s Mantle', 'Mantle of the Blackwing Cabal'};
            app.ShouldersDropDown.ValueChangedFcn = createCallbackFcn(app, @ShouldersDropDownValueChanged, true);
            app.ShouldersDropDown.Value = 'Doomcaller''s Mantle';
            app.ChestDropDown_2Label = uilabel(app.GearAndDPS);
            app.ChestDropDown_2Label.Text = 'Chest';
            app.ChestDropDown = uidropdown(app.GearAndDPS);
            app.ChestDropDown.Items = {'Robe of Volatile Power', 'Garb of Royal Ascension', 'Doomcaller''s Robes'};
            app.ChestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChestDropDownValueChanged, true);
            app.ChestDropDown.Value = 'Robe of Volatile Power';
            app.WristsDropDownLabel = uilabel(app.GearAndDPS);
            app.WristsDropDownLabel.Text = 'Wrists';
            app.WristsDropDown = uidropdown(app.GearAndDPS);
            app.WristsDropDown.Items = {'Burrower Bracers', 'Bracers of Arcane Accuracy'};
            app.WristsDropDown.ValueChangedFcn = createCallbackFcn(app, @WristsDropDownValueChanged, true);
            app.WristsDropDown.Value = 'Burrower Bracers';
            app.HandsDropDown_2Label = uilabel(app.GearAndDPS);
            app.HandsDropDown_2Label.Text = 'Hands';
            app.HandsDropDown = uidropdown(app.GearAndDPS);
            app.HandsDropDown.Items = {'Dark Storm Gauntlets', 'Nemesis Gloves', 'Ebony Flame Gloves'};
            app.HandsDropDown.ValueChangedFcn = createCallbackFcn(app, @HandsDropDownValueChanged, true);
            app.HandsDropDown.Value = 'Dark Storm Gauntlets';
            app.WaistDropDown_2Label = uilabel(app.GearAndDPS);
            app.WaistDropDown_2Label.Text = 'Waist';
            app.WaistDropDown = uidropdown(app.GearAndDPS);
            app.WaistDropDown.Items = {'Eyestalk Waistcord', 'Angelista''s Grasp'};
            app.WaistDropDown.ValueChangedFcn = createCallbackFcn(app, @WaistDropDownValueChanged, true);
            app.WaistDropDown.Value = 'Eyestalk Waistcord';
            app.LegsDropDown_2Label = uilabel(app.GearAndDPS);
            app.LegsDropDown_2Label.Text = 'Legs';
            app.LegsDropDown = uidropdown(app.GearAndDPS);
            app.LegsDropDown.Items = {'Leggings of the Black Blizzard', 'Doomcaller''s Trousers'};
            app.LegsDropDown.ValueChangedFcn = createCallbackFcn(app, @LegsDropDownValueChanged, true);
            app.LegsDropDown.Value = 'Leggings of the Black Blizzard';
            app.FeetDropDownLabel = uilabel(app.GearAndDPS);
            app.FeetDropDownLabel.Text = 'Feet';
            app.FeetDropDown = uidropdown(app.GearAndDPS);
            app.FeetDropDown.Items = {'Boots of Epiphany', 'Doomcaller''s Footwraps', 'Bloodvine Feet'};
            app.FeetDropDown.ValueChangedFcn = createCallbackFcn(app, @FeetDropDownValueChanged, true);
            app.FeetDropDown.Value = 'Boots of Epiphany';
            app.Finger1DropDownLabel = uilabel(app.GearAndDPS);
            app.Finger1DropDownLabel.Text = 'Finger 1';
            app.Finger1DropDown = uidropdown(app.GearAndDPS);
            app.Finger1DropDown.Items = {'Band of Forced Concentration', 'Ritssyn''s Ring of Chaos', 'Charm of the Shifting Sands', 'Ring of Unspoken Names', 'Ring of Spell Power', 'Band of Dark Dominion'};
            app.Finger1DropDown.ValueChangedFcn = createCallbackFcn(app, @Finger1DropDownValueChanged, true);
            app.Finger1DropDown.Value = 'Band of Forced Concentration';
            app.Trinket1DropDown_2Label = uilabel(app.GearAndDPS);
            app.Trinket1DropDown_2Label.Text = 'Trinket 1';
            app.Trinket1DropDown = uidropdown(app.GearAndDPS);
            app.Trinket1DropDown.Items = {'Talisman of Ephemeral Power'};
            app.Trinket1DropDown.Value = 'Talisman of Ephemeral Power';
            app.Trinket2DropDown_2Label = uilabel(app.GearAndDPS);
            app.Trinket2DropDown_2Label.Text = 'Trinket 2';
            app.Trinket2DropDown = uidropdown(app.GearAndDPS);
            app.Trinket2DropDown.Items = {'Neltharion''s Tear'};
            app.Trinket2DropDown.Value = 'Neltharion''s Tear';
            app.WeaponDropDown_2Label = uilabel(app.GearAndDPS);
            app.WeaponDropDown_2Label.Text = 'Weapon';
            app.WeaponDropDown = uidropdown(app.GearAndDPS);
            app.WeaponDropDown.Items = {'Blessed Qiraji Acolyte Staff', 'Staff of the Shadowflame'};
            app.WeaponDropDown.ValueChangedFcn = createCallbackFcn(app, @WeaponDropDownValueChanged, true);
            app.WeaponDropDown.Value = 'Blessed Qiraji Acolyte Staff';
            app.WandDropDown_2Label = uilabel(app.GearAndDPS);
            app.WandDropDown_2Label.Text = 'Wand';
            app.WandDropDown = uidropdown(app.GearAndDPS);
            app.WandDropDown.Items = {'Wand of Qiraji Nobility'};
            app.WandDropDown.ValueChangedFcn = createCallbackFcn(app, @WandDropDownValueChanged, true);
            app.WandDropDown.Value = 'Wand of Qiraji Nobility';
            app.OffhandDropDown_2Label = uilabel(app.GearAndDPS);
            app.OffhandDropDown_2Label.Enable = 'off';
            app.OffhandDropDown_2Label.Text = 'Offhand';
            app.OffhandDropDown = uidropdown(app.GearAndDPS);
            app.OffhandDropDown.Items = {};
            app.OffhandDropDown.Enable = 'off';
            app.OffhandDropDown.Value = {};
            app.BackDropDown_2Label = uilabel(app.GearAndDPS);
            app.BackDropDown_2Label.Text = 'Back';
            app.BackDropDown = uidropdown(app.GearAndDPS);
            app.BackDropDown.Items = {'Cloak of the Devoured', 'Cloak of the Broodlord'};
            app.BackDropDown.ValueChangedFcn = createCallbackFcn(app, @BackDropDownValueChanged, true);
            app.BackDropDown.Value = 'Cloak of the Devoured';
            app.IntEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_4.Text = 'Int';
            app.HeadInt = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadInt.Editable = 'off';
            app.HeadInt.Value = 26;
            app.SPEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_4.Text = 'SP';
            app.HeadSP = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadSP.Editable = 'off';
            app.HeadSP.Value = 33;
            app.HitLabel_4 = uilabel(app.GearAndDPS);
            app.HitLabel_4.Text = 'Hit (%)';
            app.HeadHit = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadHit.Editable = 'off';
            app.HeadHit.Value = 1;
            app.CritLabel_4 = uilabel(app.GearAndDPS);
            app.CritLabel_4.Text = 'Crit (%)';
            app.HeadCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.HeadCrit.Editable = 'off';
            app.HeadCrit.Value = 1;
            app.FightLengthsecondsEditField_2Label = uilabel(app.GearAndDPS);
            app.FightLengthsecondsEditField_2Label.Text = 'Fight Length (seconds)';
            app.FightLengthsecondsEditField = uieditfield(app.GearAndDPS, 'numeric');
            app.FightLengthsecondsEditField.Value = 120;
            app.IntEditFieldLabel_5 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_5.Text = 'Int';
            app.HandsInt = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsInt.Editable = 'off';
            app.HandsInt.Value = 26;
            app.SPEditFieldLabel_5 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_5.Text = 'SP';
            app.HandsSP = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsSP.Editable = 'off';
            app.HandsSP.Value = 33;
            app.HitLabel_5 = uilabel(app.GearAndDPS);
            app.HitLabel_5.Text = 'Hit (%)';
            app.HandsHit = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsHit.Editable = 'off';
            app.HandsHit.Value = 1;
            app.CritLabel_5 = uilabel(app.GearAndDPS);
            app.CritLabel_5.Text = 'Crit (%)';
            app.HandsCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.HandsCrit.Editable = 'off';
            app.HandsCrit.Value = 1;
            app.ExtremeLabel_3 = uilabel(app.GearAndDPS);
            app.ExtremeLabel_3.Text = 'Extreme';
            app.PreferenceSlider = uislider(app.GearAndDPS);
            app.PreferenceSlider.Limits = [0 1];
            app.PreferenceSlider.Value = 0.5;
            app.IntEditFieldLabel_6 = uilabel(app.GearAndDPS);
            app.IntEditFieldLabel_6.Text = 'Int';
            app.TotalInt = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalInt.Editable = 'off';
            app.TotalInt.Value = 206;
            app.SPEditFieldLabel_6 = uilabel(app.GearAndDPS);
            app.SPEditFieldLabel_6.Text = 'SP';
            app.TotalSP = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalSP.Editable = 'off';
            app.TotalSP.Value = 495.7;
            app.HitLabel_6 = uilabel(app.GearAndDPS);
            app.HitLabel_6.Text = 'Hit (%)';
            app.TotalHit = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalHit.Editable = 'off';
            app.TotalHit.Value = 10;
            app.CritLabel_6 = uilabel(app.GearAndDPS);
            app.CritLabel_6.Text = 'Crit (%)';
            app.TotalCrit = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalCrit.Editable = 'off';
            app.TotalCrit.Value = 7;
            app.MaxManaLabel = uilabel(app.GearAndDPS);
            app.MaxManaLabel.Text = ' Max. Mana';
            app.TotalMana = uieditfield(app.GearAndDPS, 'numeric');
            app.TotalMana.ValueDisplayFormat = '%11.6g';
            app.TotalMana.Editable = 'off';
            app.TotalMana.Value = 5803;
            app.NumberofEditFieldLabel_4 = uilabel(app.GearAndDPS);
            app.NumberofEditFieldLabel_4.Text = 'Number of ';
            app.NumberofShadowBoltCasts = uieditfield(app.GearAndDPS, 'numeric');
            app.NumberofShadowBoltCasts.Editable = 'off';
            app.CalculateButton = uibutton(app.GearAndDPS, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EstimatedDPSEditFieldLabel_2 = uilabel(app.GearAndDPS);
            app.EstimatedDPSEditFieldLabel_2.Text = 'Estimated DPS';
            app.EstimatedDPSEditField = uieditfield(app.GearAndDPS, 'numeric');
            app.EstimatedDPSEditField.Editable = 'off';
            app.SaveDPSCheckBox = uicheckbox(app.GearAndDPS);
            app.SaveDPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDPSCheckBoxValueChanged, true);
            app.SaveDPSCheckBox.Text = ' Save DPS';
            app.SavedDPSEditField = uieditfield(app.GearAndDPS, 'numeric');
            app.SavedDPSEditField.Editable = 'off';
            app.SavedDifference = uieditfield(app.GearAndDPS, 'numeric');
            app.SavedDifference.Editable = 'off';
            app.DMTCheckBox = uicheckbox(app.GearAndDPS);
            app.DMTCheckBox.ValueChangedFcn = createCallbackFcn(app, @DMTCheckBoxValueChanged, true);
            app.DMTCheckBox.Text = 'DMT';
            app.OnyCheckBox = uicheckbox(app.GearAndDPS);
            app.OnyCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnyCheckBoxValueChanged, true);
            app.OnyCheckBox.Text = 'Ony';
            app.HakkarCheckBox = uicheckbox(app.GearAndDPS);
            app.HakkarCheckBox.ValueChangedFcn = createCallbackFcn(app, @HakkarCheckBoxValueChanged, true);
            app.HakkarCheckBox.Text = 'Hakkar';
            app.ShadowElixirCheckBox = uicheckbox(app.GearAndDPS);
            app.ShadowElixirCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShadowElixirCheckBoxValueChanged, true);
            app.ShadowElixirCheckBox.Text = 'Shadow Elixir';
            app.GreaterArcaneElixirCheckBox = uicheckbox(app.GearAndDPS);
            app.GreaterArcaneElixirCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreaterArcaneElixirCheckBoxValueChanged, true);
            app.GreaterArcaneElixirCheckBox.Text = 'Greater Arcane Elixir';
            app.FlaskofSupremePowerCheckBox = uicheckbox(app.GearAndDPS);
            app.FlaskofSupremePowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlaskofSupremePowerCheckBoxValueChanged, true);
            app.FlaskofSupremePowerCheckBox.Text = 'Flask of Supreme Power';
            app.BrilliantWizardOilCheckBox = uicheckbox(app.GearAndDPS);
            app.BrilliantWizardOilCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrilliantWizardOilCheckBoxValueChanged, true);
            app.BrilliantWizardOilCheckBox.Text = 'Brilliant Wizard Oil';
            app.Rank10ShadowboltCheckBox = uicheckbox(app.GearAndDPS);
            app.Rank10ShadowboltCheckBox.ValueChangedFcn = createCallbackFcn(app, @Rank10ShadowboltCheckBoxValueChanged, true);
            app.Rank10ShadowboltCheckBox.Text = 'Rank 10 Shadowbolt';
            app.MoonkinAuraCheckBox = uicheckbox(app.GearAndDPS);
            app.MoonkinAuraCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoonkinAuraCheckBoxValueChanged, true);
            app.MoonkinAuraCheckBox.Text = 'Moonkin Aura';
            app.SongflowerCheckBox = uicheckbox(app.GearAndDPS);
            app.SongflowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @SongflowerCheckBoxValueChanged, true);
            app.SongflowerCheckBox.Text = 'Songflower';
            app.OptimizeButton = uibutton(app.GearAndDPS, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.Finger2DropDownLabel = uilabel(app.GearAndDPS);
            app.Finger2DropDownLabel.Text = 'Finger 2';
            app.Finger2DropDown = uidropdown(app.GearAndDPS);
            app.Finger2DropDown.Items = {'Band of Forced Concentration', 'Ritssyn''s Ring of Chaos', 'Charm of the Shifting Sands', 'Ring of Unspoken Names', 'Ring of Spell Power', 'Band of Dark Dominion'};
            app.Finger2DropDown.ValueChangedFcn = createCallbackFcn(app, @Finger2DropDownValueChanged, true);
            app.Finger2DropDown.Value = 'Ring of Spell Power';
            app.SavedRelativeDifference = uieditfield(app.GearAndDPS, 'text');
            app.SavedRelativeDifference.Value = '0';
            app.OptimizeGauge = uigauge(app.GearAndDPS, 'linear');
            app.OptimizeGauge.Limits = [0 1];
            app.OptimizeGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.OptimizeGauge.MajorTickLabels = {''};
            app.ManaPotionsCheckBox = uicheckbox(app.GearAndDPS);
            app.ManaPotionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManaPotionsCheckBoxValueChanged, true);
            app.ManaPotionsCheckBox.Text = 'Mana Potions';
            app.BlastedLandsIntCheckBox = uicheckbox(app.GearAndDPS);
            app.BlastedLandsIntCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlastedLandsIntCheckBoxValueChanged, true);
            app.BlastedLandsIntCheckBox.Text = 'Blasted Lands: Int';
            app.ElixiroftheSagesCheckBox = uicheckbox(app.GearAndDPS);
            app.ElixiroftheSagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElixiroftheSagesCheckBoxValueChanged, true);
            app.ElixiroftheSagesCheckBox.Text = 'Elixir of the Sages';
            app.DemonicRunesCheckBox = uicheckbox(app.GearAndDPS);
            app.DemonicRunesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DemonicRunesCheckBoxValueChanged, true);
            app.DemonicRunesCheckBox.Text = 'Demonic Runes';
            app.ArcaneIntellectCheckBox = uicheckbox(app.GearAndDPS);
            app.ArcaneIntellectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArcaneIntellectCheckBoxValueChanged, true);
            app.ArcaneIntellectCheckBox.Text = 'Arcane Intellect';
            app.AQ5setbonusCheckBox = uicheckbox(app.GearAndDPS);
            app.AQ5setbonusCheckBox.Enable = 'off';
            app.AQ5setbonusCheckBox.Text = 'AQ 5-set bonus';
            app.ManaTideCheckBox = uicheckbox(app.GearAndDPS);
            app.ManaTideCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManaTideCheckBoxValueChanged, true);
            app.ManaTideCheckBox.Text = 'Mana Tide';
            app.ManaSpringCheckBox = uicheckbox(app.GearAndDPS);
            app.ManaSpringCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManaSpringCheckBoxValueChanged, true);
            app.ManaSpringCheckBox.Text = 'Mana Spring';
            app.MagebloodCheckBox = uicheckbox(app.GearAndDPS);
            app.MagebloodCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagebloodCheckBoxValueChanged, true);
            app.MagebloodCheckBox.Text = 'Mageblood';
            app.MaxMana = uieditfield(app.GearAndDPS, 'numeric');
            app.MaxMana.ValueDisplayFormat = '%11.6g';
            app.MaxMana.Editable = 'off';
            app.MaxMana.Value = 5803;
            app.TotalManaLabel = uilabel(app.GearAndDPS);
            app.TotalManaLabel.Text = 'Total Mana';
            app.RendCheckBox = uicheckbox(app.GearAndDPS);
            app.RendCheckBox.ValueChangedFcn = createCallbackFcn(app, @RendCheckBoxValueChanged, true);
            app.RendCheckBox.Text = 'Rend';
            app.TalentsTab = uitab(app.TabGroup);
            app.TalentsTab.Title = 'Talents';
            app.TalentModificationsComingSoonLabel = uilabel(app.TalentsTab);
            app.TalentModificationsComingSoonLabel.Text = 'Talent Modifications Coming Soon!';
            app.TotalsLabel_2 = uilabel(app.TalentsTab);
            app.TotalsLabel_2.Text = 'Totals';
            app.NumberofShadowBoltCastsEditFieldLabel_3 = uilabel(app.TalentsTab);
            app.NumberofShadowBoltCastsEditFieldLabel_3.Text = 'Shadow Bolt Casts';
            app.NumberofEditFieldLabel_5 = uilabel(app.TalentsTab);
            app.NumberofEditFieldLabel_5.Text = 'Number of ';
            app.NumberofLifeTapCasts_2 = uieditfield(app.TalentsTab, 'numeric');
            app.NumberofLifeTapCasts_2.Editable = 'off';
            app.NumberofShadowBoltCastsEditFieldLabel_4 = uilabel(app.TalentsTab);
            app.NumberofShadowBoltCastsEditFieldLabel_4.Text = 'Life Tap Casts';
            app.NumberofEditFieldLabel_6 = uilabel(app.TalentsTab);
            app.NumberofEditFieldLabel_6.Text = 'Number of ';
            app.NumberofShadowBoltCasts_2 = uieditfield(app.TalentsTab, 'numeric');
            app.NumberofShadowBoltCasts_2.Editable = 'off';
            app.CalculateButton_2 = uibutton(app.TalentsTab, 'push');
            app.CalculateButton_2.Text = 'Calculate';
            app.SaveDPSCheckBox_2 = uicheckbox(app.TalentsTab);
            app.SaveDPSCheckBox_2.Text = ' Save DPS';
            app.SavedDPSEditField_2 = uieditfield(app.TalentsTab, 'numeric');
            app.SavedDPSEditField_2.Editable = 'off';
            app.SavedDifference_2 = uieditfield(app.TalentsTab, 'numeric');
            app.SavedDifference_2.Editable = 'off';
            app.SavedRelativeDifference_2 = uieditfield(app.TalentsTab, 'text');
            app.SavedRelativeDifference_2.Value = '0';
            app.FightLengthsecondsEditField_2Label_2 = uilabel(app.TalentsTab);
            app.FightLengthsecondsEditField_2Label_2.Text = 'Fight Length (seconds)';
            app.FightLengthsecondsEditField_3 = uieditfield(app.TalentsTab, 'numeric');
            app.FightLengthsecondsEditField_3.Value = 120;
            app.IntEditFieldLabel_7 = uilabel(app.TalentsTab);
            app.IntEditFieldLabel_7.Text = 'Int';
            app.TotalInt_2 = uieditfield(app.TalentsTab, 'numeric');
            app.TotalInt_2.Editable = 'off';
            app.TotalInt_2.Value = 206;
            app.SPEditFieldLabel_7 = uilabel(app.TalentsTab);
            app.SPEditFieldLabel_7.Text = 'SP';
            app.TotalSP_2 = uieditfield(app.TalentsTab, 'numeric');
            app.TotalSP_2.Editable = 'off';
            app.TotalSP_2.Value = 495.7;
            app.HitLabel_7 = uilabel(app.TalentsTab);
            app.HitLabel_7.Text = 'Hit (%)';
            app.TotalHit_2 = uieditfield(app.TalentsTab, 'numeric');
            app.TotalHit_2.Editable = 'off';
            app.TotalHit_2.Value = 10;
            app.CritLabel_7 = uilabel(app.TalentsTab);
            app.CritLabel_7.Text = 'Crit (%)';
            app.TotalCrit_2 = uieditfield(app.TalentsTab, 'numeric');
            app.TotalCrit_2.Editable = 'off';
            app.TotalCrit_2.Value = 7;
            app.ManaLabel_3 = uilabel(app.TalentsTab);
            app.ManaLabel_3.Text = 'Mana';
            app.TotalMana_2 = uieditfield(app.TalentsTab, 'numeric');
            app.TotalMana_2.ValueDisplayFormat = '%11.6g';
            app.TotalMana_2.Editable = 'off';
            app.TotalMana_2.Value = 5803;
            app.EstimatedDPSEditField_2Label = uilabel(app.TalentsTab);
            app.EstimatedDPSEditField_2Label.Text = 'Estimated DPS';
            app.EstimatedDPSEditField_2 = uieditfield(app.TalentsTab, 'numeric');
            app.EstimatedDPSEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WarlockGearApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end